classdef ex_1_day_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        BEditField       matlab.ui.control.NumericEditField
        BEditFieldLabel  matlab.ui.control.Label
        AEditField       matlab.ui.control.NumericEditField
        AEditFieldLabel  matlab.ui.control.Label
        CEditField       matlab.ui.control.NumericEditField
        CEditFieldLabel  matlab.ui.control.Label
        SUBButton        matlab.ui.control.Button
        DIVButton        matlab.ui.control.Button
        MULButton        matlab.ui.control.Button
        ADDButton        matlab.ui.control.Button
        Image            matlab.ui.control.Image
        CalculatorLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ADDButton
        function ADDButtonPushed(app, event)
          A=app.AEditField.Value;
          B=app.BEditField.Value;
          C=A+B;
          app.CEditField.Value=C;
        end

        % Button pushed function: MULButton
        function MULButtonPushed(app, event)
          A=app.AEditField.Value;
          B=app.BEditField.Value;
          C=A*B;
          app.CEditField.Value=C; 
        end

        % Button pushed function: SUBButton
        function SUBButtonPushed(app, event)
          A=app.AEditField.Value;
          B=app.BEditField.Value;
          C=A-B;
          app.CEditField.Value=C;
        end

        % Button pushed function: DIVButton
        function DIVButtonPushed(app, event)
          A=app.AEditField.Value;
          B=app.BEditField.Value;
          C=A/B;
          app.CEditField.Value=C;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculatorLabel = uilabel(app.UIFigure);
            app.CalculatorLabel.Text = 'Calculator';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'istockphoto-1137975153-612x612.jpg';
            app.ADDButton = uibutton(app.UIFigure, 'push');
            app.ADDButton.ButtonPushedFcn = createCallbackFcn(app, @ADDButtonPushed, true);
            app.ADDButton.Text = 'ADD';
            app.MULButton = uibutton(app.UIFigure, 'push');
            app.MULButton.ButtonPushedFcn = createCallbackFcn(app, @MULButtonPushed, true);
            app.MULButton.Text = 'MUL';
            app.DIVButton = uibutton(app.UIFigure, 'push');
            app.DIVButton.ButtonPushedFcn = createCallbackFcn(app, @DIVButtonPushed, true);
            app.DIVButton.Text = 'DIV';
            app.SUBButton = uibutton(app.UIFigure, 'push');
            app.SUBButton.ButtonPushedFcn = createCallbackFcn(app, @SUBButtonPushed, true);
            app.SUBButton.Text = 'SUB';
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.UIFigure, 'numeric');
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex_1_day_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end