classdef example_3_day5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AddNoiseButton            matlab.ui.control.Button
        BrouseImageButton         matlab.ui.control.Button
        ImageNoiseGeneratorLabel  matlab.ui.control.Label
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        Inputimage;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrouseImageButton
        function BrouseImageButtonPushed(app, event)
            
            [filename, pathname] = uigetfile('*.*');
            if isequal(filename,0) || isequal(pathname,0)
                disp('User pressed cancel')
            else
                img = imread(strcat(pathname, filename));
                imshow(img, 'Parent',app.UIAxes); 
                app.Inputimage=img;
                
            end
            
        end

        % Button pushed function: AddNoiseButton
        function AddNoiseButtonPushed(app, event)
            
            img=app.Inputimage;
            noise_img = imnoise(img,'salt & pepper', 0.22);
            imshow(noise_img, 'Parent', app.UIAxes_2);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Output Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.ImageNoiseGeneratorLabel = uilabel(app.UIFigure);
            app.ImageNoiseGeneratorLabel.Text = 'Image Noise Generator';
            app.BrouseImageButton = uibutton(app.UIFigure, 'push');
            app.BrouseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrouseImageButtonPushed, true);
            app.BrouseImageButton.Text = 'Brouse Image';
            app.AddNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddNoiseButtonPushed, true);
            app.AddNoiseButton.Text = 'Add Noise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = example_3_day5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end